classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure            matlab.ui.Figure
        GridLayout          matlab.ui.container.GridLayout
        LeftPanel           matlab.ui.container.Panel
        Sleepstage          matlab.ui.container.ButtonGroup
        WakeButton          matlab.ui.control.RadioButton
        REMButton           matlab.ui.control.RadioButton
        SWSButton           matlab.ui.control.RadioButton
        Button4             matlab.ui.control.RadioButton
        minButton           matlab.ui.control.Button
        UIEEG               matlab.ui.control.UIAxes
        UIAxes              matlab.ui.control.UIAxes
        secButton           matlab.ui.control.Button
        submitButton        matlab.ui.control.Button
        RightPanel          matlab.ui.container.Panel
        LoadDataButton      matlab.ui.control.Button
        dataname            matlab.ui.control.Label
        loadingstatusLabel  matlab.ui.control.Label
        UIVideo             matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = private)
        current_win % Description
        time % Description
        EEG
        t_frames % Description
        frames % Description
        f_path
        roi_x % Description
        roi_y % Description
        dist
        sleep_score % Description
        n_labeled
        r_dif
        data_name
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
                app.loadingstatusLabel.Text='Update the code, then press the Load Data';
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {519, 519};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {572, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end

        % Button pushed function: LoadDataButton
        function LoadDataButtonPushed(app, event)
            % loading the data %%%%%%%%%%%%%%%%%%%%%%
            %% setting addresses and parameters
app.loadingstatusLabel.Text='data being loaded...';
app.loadingstatusLabel.FontColor='g';
light_off_frame=31050; %%%%%%%%%%%% lights-off frame number
folder_path='Z:\zoologie\HamedData\P1\73-03\73-03 09_03_2020'; %%%%%%%%%% read video file from here
fname='09_03_2020_00090_converted'; %%%%%%%%%%%%
dir_path_server='Z:\zoologie\HamedData\P1\73-03\73-03 09_03_2020\chronic_2020-03-09_19-18-04'; %%%%%%%%
dir_path='G:\Hamed\zf\P1\73-03\09_03_2020'; %%%%%%%%%%%%% save results here
dir_prefix='133'; %%%%%%%%%%%%%%
file_dev=20; %%%%%%%%%% which prtion of app.EEG file you want to read? 10 for ane tenth of the file
chnl_order=[1 2 3 4 5 6 8 7 10 9 11 12 13 16 14 15];% 1 2 3 4 5 6 8 7 10 9 11 12 13 16 14 15 

[~,app.data_name,~]=fileparts(folder_path);
app.dataname.Text=['Data: ' app.data_name];
%% preparing the app.EEG and video for the GUI

app.f_path=[folder_path '\' fname '.avi'];
vid=VideoReader(app.f_path);
f0=1; % 1st frame
fn=ceil(vid.FrameRate*vid.Duration/20)-1000;  %%%%%%%%%% last frame %%%%%%%%% /10
app.frames=f0: fn; % frames to be analyzed
app.roi_y=1:1024;  app.roi_x=1:1280; % where is the region of interest?

mkdir(dir_path);

% initializing the sleep_score structure
str_size = round(app.frames(end)/60)+1000;  %number of elements
sleep_score_(str_size) = struct('time',[],'label',[],'EEG',[],'movement',[]);
app.sleep_score=sleep_score_;
app.n_labeled=0;

% app.time frames for video frames: loading synchroniying ADC channel
app.loadingstatusLabel.Text='app.time stamps being loaded...';
downsamp_ratio=1; % must be a power of 2, as the file reader reads blocks of 1024 samples each app.time
file_dev_adc=20; %%%%%%% which poetion of file you want to read? 10 for ane tenth of the file
[ ADC, time_adc, ~]=OpenEphys2MAT_load_save_Data(1, [dir_prefix '_ADC'], downsamp_ratio, file_dev_adc,...
    dir_path_server);

%Extracting synchroniying app.times of frames
app.loadingstatusLabel.Text='app.time stamps being extracted...';
[peak_indx]=find(ADC.*(ADC>4));
jumps_to_new_frame_indx=[diff(peak_indx)>5; true];
unique_peak_indx=peak_indx(jumps_to_new_frame_indx);
t_frames_=time_adc(unique_peak_indx);
clear unique_peak_indx jumps_to_new_frame_indx ADC time_adc peak_indx
app.t_frames=t_frames_(app.frames);
t_light_off=app.t_frames(light_off_frame-f0+1);

% load app.EEG as .continuous
app.loadingstatusLabel.Text='app.EEG being loaded...';
app.loadingstatusLabel.FontColor='k';
downsamp_ratio=128; %%%%%%%%% must be a power of 2, as the file reader reads blocks of 1024 samples each app.time
file_dev=10; %%%%%%%%%
[ app.EEG, app.time, ~]=OpenEphys2MAT_load_save_Data(chnl_order, [dir_prefix '_CH'], downsamp_ratio, file_dev,...
    dir_path_server);
app.dist=.5*std(app.EEG(:));
app.loadingstatusLabel.Text='app.EEG data loaded';
app.current_win=app.t_frames(1)+5; % first window-center app.time
      

% app.EEG ploting and video frame display
% plot app.EEG
t_plot_lim=[app.current_win-4.5 app.current_win+4.5];  %%%%%%%%%%% app.time for plotting
t_plot_ind=(app.time>t_plot_lim(1) & app.time<t_plot_lim(2));
t_plot=app.time(t_plot_ind);
EEG_plot=app.EEG(t_plot_ind,:);
for chnl=1:size(app.EEG,2)
    plot(app.UIEEG,t_plot,EEG_plot(:,chnl)+chnl*app.dist);
    hold(app.UIEEG,"on");
end
xlim(app.UIEEG,t_plot_lim);
gray= [127 127 127]./255;
area(app.UIEEG,[t_plot_lim(1) t_plot_lim(1)+3] ,(chnl+1)*app.dist*[1 1],'basevalue',0,'FaceColor',gray,...
    'LineStyle','none','FaceAlpha',.5);
area(app.UIEEG,[t_plot_lim(end)-3 t_plot_lim(end)] ,(chnl+1)*app.dist*[1 1],'basevalue',0,'FaceColor',gray,...
    'LineStyle','none','FaceAlpha',.5);
    hold(app.UIEEG,"off");
ylim(app.UIEEG, [0 (chnl+1)*app.dist]);

% display video
win_frames=app.frames(app.t_frames>t_plot(1) & app.t_frames<t_plot_lim(2));
[app.r_dif,acc_dif, last_im, ~] = birdvid_move_extract_app(app.f_path,win_frames,app.roi_y,app.roi_x);
faint_im1=.6*last_im+50; % mapping to confinding the pixel intensities to 200-250 instead of full range (0-255)
acc_difim = cat(3, faint_im1, faint_im1-1*acc_dif, faint_im1-1*acc_dif); % the difference just depics in Red
% imshow(uint8(acc_difim), 'parent', app.UIVideo);
imshow(uint8(acc_difim), 'Parent', app.UIVideo);

% updating the sleep time indicator
current_place=100*win_frames(end)/app.frames(end);
line(app.UIAxes,[current_place current_place],[0 1],'linewidth',2);
xlim(app.UIAxes,[0 100]);

        end

        % Button pushed function: secButton
        function secButtonPushed(app, event)
app.current_win=app.current_win+3; % first window-center app.time
      
% app.EEG ploting and video frame display
% plot app.EEG
t_plot_lim=[app.current_win-4.5 app.current_win+4.5];  %%%%%%%%%%% app.time for plotting
t_plot_ind=(app.time>t_plot_lim(1) & app.time<t_plot_lim(2));
t_plot=app.time(t_plot_ind);
EEG_plot=app.EEG(t_plot_ind,:);
for chnl=1:size(app.EEG,2)
    plot(app.UIEEG,t_plot,EEG_plot(:,chnl)+chnl*app.dist);
    hold(app.UIEEG,"on");
end
xlim(app.UIEEG,t_plot_lim);
gray= [127 127 127]./255;
area(app.UIEEG,[t_plot_lim(1) t_plot_lim(1)+3] ,(chnl+1)*app.dist*[1 1],'basevalue',0,'FaceColor',gray,...
    'LineStyle','none','FaceAlpha',.5);
area(app.UIEEG,[t_plot_lim(end)-3 t_plot_lim(end)] ,(chnl+1)*app.dist*[1 1],'basevalue',0,'FaceColor',gray,...
    'LineStyle','none','FaceAlpha',.5);
hold(app.UIEEG,"off");
ylim(app.UIEEG, [0 (chnl+1)*app.dist]);

% display video
win_frames=app.frames(app.t_frames>t_plot(1)+3 & app.t_frames<t_plot_lim(2)-3);
[app.r_dif,acc_dif, last_im, ~] = birdvid_move_extract_app(app.f_path,win_frames,app.roi_y,app.roi_x);
faint_im1=.6*last_im+50; % mapping to confinding the pixel intensities to 200-250 instead of full range (0-255)
acc_difim = cat(3, faint_im1, faint_im1-1*acc_dif, faint_im1-1*acc_dif); % the difference just depics in Red
% imshow(uint8(acc_difim), 'parent', app.UIVideo);
imshow(uint8(acc_difim), 'Parent', app.UIVideo);

% updating the sleep time indicator
current_place=100*win_frames(end)/app.frames(end);
line(app.UIAxes,[current_place current_place],[0 1],'linewidth',2);
xlim(app.UIAxes,[0 100]);

        end

        % Button pushed function: minButton
        function minButtonPushed(app, event)
            app.current_win=app.current_win+20*60;
      
% app.EEG ploting and video frame display
% plot app.EEG
t_plot_lim=[app.current_win-4.5 app.current_win+4.5];  %%%%%%%%%%% app.time for plotting
t_plot_ind=(app.time>t_plot_lim(1) & app.time<t_plot_lim(2));
t_plot=app.time(t_plot_ind);
EEG_plot=app.EEG(t_plot_ind,:);
for chnl=1:size(app.EEG,2)
    plot(app.UIEEG,t_plot,EEG_plot(:,chnl)+chnl*app.dist);
    hold(app.UIEEG,"on");
end
xlim(app.UIEEG,t_plot_lim);
gray= [127 127 127]./255;
area(app.UIEEG,[t_plot_lim(1) t_plot_lim(1)+3] ,(chnl+1)*app.dist*[1 1],'basevalue',0,'FaceColor',gray,...
    'LineStyle','none','FaceAlpha',.5);
area(app.UIEEG,[t_plot_lim(end)-3 t_plot_lim(end)] ,(chnl+1)*app.dist*[1 1],'basevalue',0,'FaceColor',gray,...
    'LineStyle','none','FaceAlpha',.5);
hold(app.UIEEG,"off");
ylim(app.UIEEG, [0 (chnl+1)*app.dist]);

% display video
win_frames=app.frames(app.t_frames>t_plot(1)+3 & app.t_frames<t_plot_lim(2)-3);
[app.r_dif,acc_dif, last_im, ~] = birdvid_move_extract_app(app.f_path,win_frames,app.roi_y,app.roi_x);
faint_im1=.6*last_im+50; % mapping to confinding the pixel intensities to 200-250 instead of full range (0-255)
acc_difim = cat(3, faint_im1, faint_im1-1*acc_dif, faint_im1-1*acc_dif); % the difference just depics in Red
% imshow(uint8(acc_difim), 'parent', app.UIVideo);
imshow(uint8(acc_difim), 'Parent', app.UIVideo);

% updating the sleep time indicator
current_place=100*win_frames(end)/app.frames(end);
line(app.UIAxes,[current_place current_place],[0 1],'linewidth',2);
xlim(app.UIAxes,[0 100]);

% deliver the variables to the workspace
assignin('base','sleep_scores',app.sleep_score);
        end

        % Button pushed function: submitButton
        function submitButtonPushed(app, event)
            app.n_labeled=app.n_labeled+1;
            label = app.Sleepstage.SelectedObject.Text;
            % write the result in the sleep_score 
            app.sleep_score(app.n_labeled).time=app.current_win; % center of the time window
            app.sleep_score(app.n_labeled).label=label;
            EEG_ind=(app.time>app.current_win-2.5 & app.time<app.current_win+2.5);
            app.sleep_score(app.n_labeled).EEG=app.EEG(EEG_ind,:);
            app.sleep_score(app.n_labeled).movement=app.r_dif;

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.Sleepstage = uibuttongroup(app.LeftPanel);
            app.Sleepstage.Title = 'Sleep stage:';
            app.WakeButton = uiradiobutton(app.Sleepstage);
            app.WakeButton.Text = 'Wake';
            app.WakeButton.Value = true;
            app.REMButton = uiradiobutton(app.Sleepstage);
            app.REMButton.Text = 'REM';
            app.SWSButton = uiradiobutton(app.Sleepstage);
            app.SWSButton.Text = 'SWS';
            app.Button4 = uiradiobutton(app.Sleepstage);
            app.Button4.Text = 'IS';
            app.minButton = uibutton(app.LeftPanel, 'push');
            app.minButton.ButtonPushedFcn = createCallbackFcn(app, @minButtonPushed, true);
            app.minButton.Text = '>>20min';
            app.UIEEG = uiaxes(app.LeftPanel);
            title(app.UIEEG, 'EEG')
            xlabel(app.UIEEG, 'Time (s)')
            ylabel(app.UIEEG, '')
            app.UIEEG.XTick = [];
            app.UIEEG.XTickLabel = '';
            app.UIEEG.YTick = [];
            app.UIAxes = uiaxes(app.LeftPanel);
            title(app.UIAxes, '')
            xlabel(app.UIAxes, 'Progress (%)')
            ylabel(app.UIAxes, '')
            app.UIAxes.YTick = [];
            app.UIAxes.YTickLabel = '';
            app.secButton = uibutton(app.LeftPanel, 'push');
            app.secButton.ButtonPushedFcn = createCallbackFcn(app, @secButtonPushed, true);
            app.secButton.Text = '>3sec';
            app.submitButton = uibutton(app.LeftPanel, 'push');
            app.submitButton.ButtonPushedFcn = createCallbackFcn(app, @submitButtonPushed, true);
            app.submitButton.Text = 'submit';
            app.RightPanel = uipanel(app.GridLayout);
            app.LoadDataButton = uibutton(app.RightPanel, 'push');
            app.LoadDataButton.ButtonPushedFcn = createCallbackFcn(app, @LoadDataButtonPushed, true);
            app.LoadDataButton.Text = 'Load Data';
            app.dataname = uilabel(app.RightPanel);
            app.dataname.Text = 'data name';
            app.loadingstatusLabel = uilabel(app.RightPanel);
            app.loadingstatusLabel.Text = 'loading status';
            app.UIVideo = uiaxes(app.RightPanel);
            title(app.UIVideo, '')
            xlabel(app.UIVideo, '')
            ylabel(app.UIVideo, '')
            app.UIVideo.XTick = [];
            app.UIVideo.YTick = [];
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end